classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        CustomerVoltagesTab            matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        error                          matlab.ui.control.UIAxes
        estCustVolt                    matlab.ui.control.UIAxes
        custVolt                       matlab.ui.control.UIAxes
        MultiObserverTab               matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        observerUsage                  matlab.ui.control.UIAxes
        BestObserver                   matlab.ui.control.EditField
        AttackedOutputs                matlab.ui.control.EditField
        RunButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        InitialConditionsEditField     matlab.ui.control.EditField
        InitialConditionsEditFieldLabel  matlab.ui.control.Label
        SimulationTimesSlider          matlab.ui.control.Slider
        SimulationTimesSliderLabel     matlab.ui.control.Label
        SimulationParametersLabel      matlab.ui.control.Label
        SystemTab                      matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        InputfilenameEditField         matlab.ui.control.EditField
        InputfilenameEditFieldLabel    matlab.ui.control.Label
        AttacksignalEditField          matlab.ui.control.EditField
        AttacksignalEditFieldLabel     matlab.ui.control.Label
        SubstationvoltageEditField     matlab.ui.control.EditField
        SubstationvoltageEditFieldLabel  matlab.ui.control.Label
        NumberofCustomersSpinner       matlab.ui.control.Spinner
        NumberofCustomersSpinnerLabel  matlab.ui.control.Label
        NumberofAttacksSpinner         matlab.ui.control.Spinner
        NumberofAttacksSpinnerLabel    matlab.ui.control.Label
        OutputstobeattackedNoselectionchoosesrandomoutputsListBox  matlab.ui.control.ListBox
        OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel  matlab.ui.control.Label
        SystemParametersLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clearvars PDMO;
            numCustomers    = app.NumberofCustomersSpinner.Value;
            numAttacks      = app.NumberofAttacksSpinner.Value;
            attackedOutputs = app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Value;
            attackFunc      = str2func(['@(t)' app.AttacksignalEditField.Value]);
            v0              = str2func(['@(t)',app.SubstationvoltageEditField.Value]);
            if isempty(attackedOutputs)
                attackedOutputs = "";
            end
            attackedOutputs = str2num(attackedOutputs); %#ok<ST2NM> 
            tspan           = [0 app.SimulationTimesSlider.Value];
            x0              = app.InitialConditionsEditField.Value;
            if isempty(x0)
                x0 = -0.5 + rand(numCustomers,1);
            else
                x0 = str2double(x0);
            end
            if size(x0,2) == 1
                x0 = repmat(x0,1,numCustomers);
            end
            assert(size(x0,2)==numCustomers,...
                "The amount of initial conditions (%d) does not equal the number of customers (%d)",...
                size(x0,2),numCustomers);
            inputFileName   = app.InputfilenameEditField.Value;
            PDMO = PowerDistMO(numCustomers,numAttacks,attackedOutputs,v0,attackFunc,inputFileName);

            [t,v,e,obsv] = PDMO.solve(tspan,x0);
            % plot customer voltages
            plot(app.custVolt,t,[v0(t); v(:,:,1)]);
            grayColor = [.7 .7 .7];
            yline(app.custVolt,230,'--','Color',grayColor)
            yline(app.custVolt,230*1.1,'--','Color',grayColor)
            yline(app.custVolt,230*0.9,'--','Color',grayColor)
            legendEntries = arrayfun(@(x) sprintf('v_%d', x), 0:PDMO.numCustomers, 'UniformOutput', false);
            legend(app.custVolt,legendEntries)
            xlim(app.custVolt,tspan)

            % plot estimated customer voltages
            plot(app.estCustVolt,t,[v0(t); v(:,:,2)]);
            grayColor = [.7 .7 .7];
            yline(app.estCustVolt,230,'--','Color',grayColor)
            yline(app.estCustVolt,230*1.1,'--','Color',grayColor)
            yline(app.estCustVolt,230*0.9,'--','Color',grayColor)
            legendEntries = arrayfun(@(x) sprintf('v_%d', x), 0:PDMO.numCustomers, 'UniformOutput', false);
            legend(app.estCustVolt,legendEntries)
            xlim(app.estCustVolt,tspan)

            % plot multi observer error
            colorOrder = get(app.error, 'ColorOrder'); % Get default color order
            set(app.error,'ColorOrder',colorOrder(2:end,:));
            plot(app.error,t,e)
            legendEntries = arrayfun(@(x) sprintf('e_%d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
            legend(app.error,legendEntries);
            xlim(app.error,tspan);
            grid(app.error,"on");

            % plot observer usage
            colors = lines(PDMO.numObservers);
            for o = 1:PDMO.numObservers
                idx = (obsv == o);
                if any(idx)
                    h = stem(app.observerUsage, 'on', t(idx), obsv(idx)); % Create stem plot
                    set(h, 'Color', colors(o, :)); % Set color properly
                end
            end

            % edit text values below plots
            app.AttackedOutputs.Value = strcat("Attacked Outputs: ", num2str(PDMO.attack.attackedOutputs));
            app.BestObserver.Value    = strcat("Best Observer: ", num2str(obsv(end)), ", Outputs: ", num2str(PDMO.primaryMO.CSetIndices(obsv(end),:)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.GridLayout = uigridlayout(app.SystemTab);
            app.SystemParametersLabel = uilabel(app.GridLayout);
            app.SystemParametersLabel.Text = 'System Parameters';
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel = uilabel(app.GridLayout);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel.Text = {'Outputs to be attacked'; '(No selection  chooses'; 'random outputs, hold control to select multiple)'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox = uilistbox(app.GridLayout);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Multiselect = 'on';
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Value = {};
            app.NumberofAttacksSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofAttacksSpinnerLabel.Text = 'Number of Attacks';
            app.NumberofAttacksSpinner = uispinner(app.GridLayout);
            app.NumberofAttacksSpinner.Limits = [0 50];
            app.NumberofAttacksSpinner.Value = 2;
            app.NumberofCustomersSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofCustomersSpinnerLabel.Text = 'Number of Customers';
            app.NumberofCustomersSpinner = uispinner(app.GridLayout);
            app.NumberofCustomersSpinner.Limits = [0 50];
            app.NumberofCustomersSpinner.Value = 5;
            app.SubstationvoltageEditFieldLabel = uilabel(app.GridLayout);
            app.SubstationvoltageEditFieldLabel.Text = 'Substation voltage';
            app.SubstationvoltageEditField = uieditfield(app.GridLayout, 'text');
            app.SubstationvoltageEditField.Tooltip = {'The substation voltage signal. Input is parsed by str2func, the anonymous function handle @(t) is added in the backend.'};
            app.SubstationvoltageEditField.Value = '230 + 5*sin(t)';
            app.AttacksignalEditFieldLabel = uilabel(app.GridLayout);
            app.AttacksignalEditFieldLabel.Text = 'Attack signal';
            app.AttacksignalEditField = uieditfield(app.GridLayout, 'text');
            app.AttacksignalEditField.Tooltip = {'The attack signal used on all attacked outputs. Input is parsed by str2func, the anonymous function handle @(t) is added in the backend.'};
            app.AttacksignalEditField.Value = '1000*t';
            app.InputfilenameEditFieldLabel = uilabel(app.GridLayout);
            app.InputfilenameEditFieldLabel.Text = 'Input file name';
            app.InputfilenameEditField = uieditfield(app.GridLayout, 'text');
            app.InputfilenameEditField.Tooltip = {'If this field is left empty a standard system is generated using the settings below.'};
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.GridLayout3 = uigridlayout(app.SimulationTab);
            app.SimulationParametersLabel = uilabel(app.GridLayout3);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.SimulationTimesSliderLabel = uilabel(app.GridLayout3);
            app.SimulationTimesSliderLabel.Text = {'Simulation '; 'Time [s]'};
            app.SimulationTimesSlider = uislider(app.GridLayout3);
            app.SimulationTimesSlider.Limits = [0 50];
            app.SimulationTimesSlider.Value = 10;
            app.InitialConditionsEditFieldLabel = uilabel(app.GridLayout3);
            app.InitialConditionsEditFieldLabel.Text = 'Initial Conditions';
            app.InitialConditionsEditField = uieditfield(app.GridLayout3, 'text');
            app.InitialConditionsEditField.Value = '0';
            app.RunButton = uibutton(app.GridLayout2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Simulate the model with the entered parameters'};
            app.RunButton.Text = 'Run';
            app.AttackedOutputs = uieditfield(app.GridLayout2, 'text');
            app.AttackedOutputs.Editable = 'off';
            app.AttackedOutputs.Value = 'Attacked Outputs:';
            app.BestObserver = uieditfield(app.GridLayout2, 'text');
            app.BestObserver.Editable = 'off';
            app.BestObserver.Value = 'Best Observer:  Outputs:';
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.CustomerVoltagesTab = uitab(app.TabGroup2);
            app.CustomerVoltagesTab.Title = 'Customer Voltages';
            app.GridLayout4 = uigridlayout(app.CustomerVoltagesTab);
            app.custVolt = uiaxes(app.GridLayout4);
            title(app.custVolt, 'Customer Voltages')
            xlabel(app.custVolt, 'Time [s]')
            ylabel(app.custVolt, 'Voltage [V]')
            zlabel(app.custVolt, 'Z')
            app.estCustVolt = uiaxes(app.GridLayout4);
            title(app.estCustVolt, 'Estimated Customer Voltages')
            xlabel(app.estCustVolt, 'Time [s]')
            ylabel(app.estCustVolt, 'Voltage [V]')
            zlabel(app.estCustVolt, 'Z')
            app.error = uiaxes(app.GridLayout4);
            title(app.error, 'Observer error')
            xlabel(app.error, 'Time [s]')
            ylabel(app.error, 'Error [V]')
            zlabel(app.error, 'Z')
            app.MultiObserverTab = uitab(app.TabGroup2);
            app.MultiObserverTab.Title = 'Multi Observer';
            app.GridLayout5 = uigridlayout(app.MultiObserverTab);
            app.observerUsage = uiaxes(app.GridLayout5);
            title(app.observerUsage, 'Best Observer')
            xlabel(app.observerUsage, 'Time [s]')
            ylabel(app.observerUsage, 'Observer ID')
            zlabel(app.observerUsage, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end